classdef BLapp2020 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        TpSliderLabel         matlab.ui.control.Label
        TpSlider              matlab.ui.control.Slider
        TfSliderLabel         matlab.ui.control.Label
        TfSlider              matlab.ui.control.Slider
        TvSliderLabel         matlab.ui.control.Label
        TvSlider              matlab.ui.control.Slider
        TpLabel               matlab.ui.control.Label
        TfLabel               matlab.ui.control.Label
        TvLabel               matlab.ui.control.Label
        CirculatoryCheckBox   matlab.ui.control.CheckBox
        ImpulsiveCheckBox     matlab.ui.control.CheckBox
        PotentialCheckBox     matlab.ui.control.CheckBox
        AccVortexCheckBox     matlab.ui.control.CheckBox
        TotalCheckBox         matlab.ui.control.CheckBox
        ExperimentalCheckBox  matlab.ui.control.CheckBox
        ModKirchoffCheckBox   matlab.ui.control.CheckBox
        LaggedCheckBox        matlab.ui.control.CheckBox
        DatasetDropDownLabel  matlab.ui.control.Label
        DatasetDropDown       matlab.ui.control.DropDown
        RightPanel            matlab.ui.container.Panel
        TabGroup              matlab.ui.container.TabGroup
        loadsTab              matlab.ui.container.Tab
        UIAxes                matlab.ui.control.UIAxes
        SaveFigureButton      matlab.ui.control.Button
        seppointTab           matlab.ui.container.Tab
        UIAxes2               matlab.ui.control.UIAxes
        SaveFigureButton_2    matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        % pitching motion
        pitching
        airfoil
        a = 340.3;
        Tp = 1;
        Tf = 1;
        Tv = 1;
        cmap
    end
    
    methods (Access = private)
        function updatePlots(app)
            app.pitching.BeddoesLeishman(app.airfoil,app.Tp,app.Tf,app.Tv);
            app.plotCN(app.pitching.CN_LB,app.pitching.CNI,app.pitching.CNC,app.pitching.CNp,app.pitching.CNprime,app.pitching.CNf,app.pitching.CNv)
            app.plotf(app.pitching.f,app.pitching.fp,app.pitching.fpp)
        end
        
        function plotCN(app,CN_LB,CNI,CNC,CNp,CNprime,CNf,CNv)
            hold(app.UIAxes,'off')
            
            if app.ExperimentalCheckBox.Value
                plot(app.UIAxes,app.pitching.alpha,app.pitching.CN,"Color",app.cmap(1,:),"LineWidth",2,'DisplayName','exp')
                hold(app.UIAxes,'on')
            end
            
            if app.CirculatoryCheckBox.Value
                plot(app.UIAxes,app.pitching.alpha(1:length(CNC)),CNC,"Color",app.cmap(2,:),"LineWidth",1,'DisplayName','C_N^C')
                hold(app.UIAxes,'on')
            end
            
            if app.ImpulsiveCheckBox.Value
                plot(app.UIAxes,app.pitching.alpha(1:length(CNI)),CNI,"Color",app.cmap(3,:),"LineWidth",1,'DisplayName','C_N^I')
                hold(app.UIAxes,'on')
            end
            
            if app.PotentialCheckBox.Value
                plot(app.UIAxes,app.pitching.alpha(1:length(CNp)),CNp,"Color",app.cmap(4,:),"LineWidth",1,'DisplayName','C_N^p')
                hold(app.UIAxes,'on')
            end
            
            if app.LaggedCheckBox.Value
                plot(app.UIAxes,app.pitching.alpha(1:length(CN_LB)),CNprime,"Color",app.cmap(5,:),"LineWidth",1,'DisplayName','C_N''')
                hold(app.UIAxes,'on')
            end
            
            if app.ModKirchoffCheckBox.Value
                plot(app.UIAxes,app.pitching.alpha(1:length(CNf)),CNf,"Color",app.cmap(6,:),"LineWidth",1,'DisplayName','C_N^f')
                hold(app.UIAxes,'on')
            end
            
            if app.AccVortexCheckBox.Value
                plot(app.UIAxes,app.pitching.alpha(1:length(CNv)),CNv,"Color",app.cmap(7,:),"LineWidth",1,'DisplayName','C_N^v')
                hold(app.UIAxes,'on')
            end
            
            if app.TotalCheckBox.Value
                plot(app.UIAxes,app.pitching.alpha(1:length(CN_LB)),CN_LB,"Color",'r',"LineWidth",2,'DisplayName','total')
                hold(app.UIAxes,'on')
            end
            
            legend(app.UIAxes,'Location','NorthWest')
        end
        
        function plotf(app,f,fp,fpp)
            hold(app.UIAxes2,'off')
            plot(app.UIAxes2,app.pitching.alpha(1:length(f)),f,"LineWidth",1,'DisplayName','f')
            hold(app.UIAxes2,'on')
            plot(app.UIAxes2,app.pitching.alpha(1:length(fp)),fp,"LineWidth",1,'DisplayName','f''')
            plot(app.UIAxes2,app.pitching.alpha(1:length(fpp)),fpp,"LineWidth",1,'DisplayName','f''''')
            legend(app.UIAxes2,'Location','SouthWest')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            switch app.DatasetDropDown.Value
                case 'Leishman 1989'
                    app.airfoil = Airfoil('naca0012',0.457);
                    data = load('naca0012.mat');
                    data = data.naca0012;
                    
                    app.pitching = PitchingMotion('alpha',data.alpha_xp,'CN',data.CN_xp,'V',0.3*app.a,'k',0.1);
                    app.pitching.setName()
                    app.pitching.setSinus(app.airfoil,deg2rad(data.mean),deg2rad(data.amp),2*pi/0.032,-pi/2);
                    
                    app.airfoil.steady = SteadyCurve(data.alpha_st,data.CN_st);
                case 'simcos 2008'
                    run('data/2008_simcos/matlab/labbook.m')

                    % Static data                    
                    app.airfoil = Airfoil('OA209',0.3);

                    load('static_corr','mA','mCl_corr')
                    app.airfoil.steady = SteadyCurve(mA,mCl_corr);
                    
                    % Dynamic data
                    nr=13;
                    
                    data = load(pressuredata(nr));

                    % Drag missing, compute the pressure drag
                    CD = zeros(size(data.Cl));
                    for k=1:length(data.Cl)
                        CD(k) = sum(data.Cp(k,:)*data.xk);
                    end
                    
                    load('dynamic_corr','Alpha','Cl_corr')
                    % assuming CL = CN
                    app.pitching = PitchingMotion('alpha',Alpha,'CN',Cl_corr,'k',LB(nr).k,'freq',LB(nr).fosc,'V',50);
                    app.pitching.setSinus(app.airfoil,deg2rad(LB(nr).alpha_0),deg2rad(LB(nr).alpha_1),LB(nr).fosc,LB(nr).FS);
                    app.pitching.setName('simcos')
                    app.pitching.setCNsteady(app.airfoil.steady)
                    
                    % set static slope and zero lift
                    app.airfoil.steady.computeSlope();
                    app.airfoil.steady.CN0 = app.airfoil.steady.CN(1);
                    
                    app.airfoil.steady.fitKirchhoff();
                    app.airfoil.steady.plotKirchhoff();
                    
            end
            app.airfoil.steady.fitKirchhoff();
            
            colormap(app.UIAxes,'parula')
            app.cmap = app.UIAxes.ColorOrder;
            app.TotalCheckBox.Value = 1;
            app.ExperimentalCheckBox.Value = 1;
            app.updatePlots()
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {214, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: TpSlider
        function TpSliderValueChanged(app, event)
            value = app.TpSlider.Value;
            app.Tp = value;
            app.updatePlots()
            app.TpLabel.Text = sprintf('%.2f',value);
        end

        % Value changed function: TfSlider
        function TfSliderValueChanged(app, event)
            value = app.TfSlider.Value;
            app.Tf = value;
            app.updatePlots()
            app.TfLabel.Text = sprintf('%.2f',value);
        end

        % Value changed function: TvSlider
        function TvSliderValueChanged(app, event)
            value = app.TvSlider.Value;
            app.Tv = value;
            app.updatePlots()
            app.TvLabel.Text = sprintf('%.2f',value);
        end

        % Value changed function: CirculatoryCheckBox
        function CirculatoryCheckBoxValueChanged(app, event)
            app.updatePlots()
        end

        % Value changed function: ImpulsiveCheckBox
        function ImpulsiveCheckBoxValueChanged(app, event)
            app.updatePlots()
        end

        % Value changed function: PotentialCheckBox
        function PotentialCheckBoxValueChanged(app, event)
            app.updatePlots()
        end

        % Value changed function: LaggedCheckBox
        function LaggedCheckBoxValueChanged(app, event)
            app.updatePlots()
        end

        % Value changed function: ModKirchoffCheckBox
        function ModKirchoffCheckBoxValueChanged(app, event)
            app.updatePlots()
        end

        % Value changed function: AccVortexCheckBox
        function AccVortexCheckBoxValueChanged(app, event)
            app.updatePlots()
        end

        % Value changed function: TotalCheckBox
        function TotalCheckBoxValueChanged(app, event)
            app.updatePlots()
        end

        % Value changed function: ExperimentalCheckBox
        function ExperimentalCheckBoxValueChanged(app, event)
           app.updatePlots()
        end

        % Button pushed function: SaveFigureButton
        function SaveFigureButtonPushed(app, event)
            exportgraphics(app.UIAxes,'fig/CN_curves.png')
        end

        % Button pushed function: SaveFigureButton_2
        function SaveFigureButton_2Pushed(app, event)
            exportgraphics(app.UIAxes,'fig/f_curves.png')
        end

        % Value changed function: DatasetDropDown
        function DatasetDropDownValueChanged(app, event)
            app.startupFcn() % reload the correct dataset
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TpSliderLabel = uilabel(app.LeftPanel);
            app.TpSliderLabel.Text = 'Tp';
            app.TpSlider = uislider(app.LeftPanel);
            app.TpSlider.Limits = [0 10];
            app.TpSlider.ValueChangedFcn = createCallbackFcn(app, @TpSliderValueChanged, true);
            app.TpSlider.Value = 1;
            app.TfSliderLabel = uilabel(app.LeftPanel);
            app.TfSliderLabel.Text = 'Tf';
            app.TfSlider = uislider(app.LeftPanel);
            app.TfSlider.Limits = [0 10];
            app.TfSlider.ValueChangedFcn = createCallbackFcn(app, @TfSliderValueChanged, true);
            app.TfSlider.Value = 1;
            app.TvSliderLabel = uilabel(app.LeftPanel);
            app.TvSliderLabel.Text = 'Tv';
            app.TvSlider = uislider(app.LeftPanel);
            app.TvSlider.Limits = [0 10];
            app.TvSlider.ValueChangedFcn = createCallbackFcn(app, @TvSliderValueChanged, true);
            app.TvSlider.Value = 1;
            app.TpLabel = uilabel(app.LeftPanel);
            app.TpLabel.Text = '1.0';
            app.TfLabel = uilabel(app.LeftPanel);
            app.TfLabel.Text = '1.0';
            app.TvLabel = uilabel(app.LeftPanel);
            app.TvLabel.Text = '1.0';
            app.CirculatoryCheckBox = uicheckbox(app.LeftPanel);
            app.CirculatoryCheckBox.ValueChangedFcn = createCallbackFcn(app, @CirculatoryCheckBoxValueChanged, true);
            app.CirculatoryCheckBox.Text = 'Circulatory';
            app.ImpulsiveCheckBox = uicheckbox(app.LeftPanel);
            app.ImpulsiveCheckBox.ValueChangedFcn = createCallbackFcn(app, @ImpulsiveCheckBoxValueChanged, true);
            app.ImpulsiveCheckBox.Text = 'Impulsive';
            app.PotentialCheckBox = uicheckbox(app.LeftPanel);
            app.PotentialCheckBox.ValueChangedFcn = createCallbackFcn(app, @PotentialCheckBoxValueChanged, true);
            app.PotentialCheckBox.Text = 'Potential';
            app.AccVortexCheckBox = uicheckbox(app.LeftPanel);
            app.AccVortexCheckBox.ValueChangedFcn = createCallbackFcn(app, @AccVortexCheckBoxValueChanged, true);
            app.AccVortexCheckBox.Text = 'Acc. Vortex';
            app.TotalCheckBox = uicheckbox(app.LeftPanel);
            app.TotalCheckBox.ValueChangedFcn = createCallbackFcn(app, @TotalCheckBoxValueChanged, true);
            app.TotalCheckBox.Text = 'Total';
            app.ExperimentalCheckBox = uicheckbox(app.LeftPanel);
            app.ExperimentalCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExperimentalCheckBoxValueChanged, true);
            app.ExperimentalCheckBox.Text = 'Experimental';
            app.ModKirchoffCheckBox = uicheckbox(app.LeftPanel);
            app.ModKirchoffCheckBox.ValueChangedFcn = createCallbackFcn(app, @ModKirchoffCheckBoxValueChanged, true);
            app.ModKirchoffCheckBox.Text = 'Mod. Kirchoff';
            app.LaggedCheckBox = uicheckbox(app.LeftPanel);
            app.LaggedCheckBox.ValueChangedFcn = createCallbackFcn(app, @LaggedCheckBoxValueChanged, true);
            app.LaggedCheckBox.Text = 'Lagged';
            app.DatasetDropDownLabel = uilabel(app.LeftPanel);
            app.DatasetDropDownLabel.Text = 'Dataset';
            app.DatasetDropDown = uidropdown(app.LeftPanel);
            app.DatasetDropDown.Items = {'Leishman 1989', 'simcos 2008'};
            app.DatasetDropDown.ValueChangedFcn = createCallbackFcn(app, @DatasetDropDownValueChanged, true);
            app.DatasetDropDown.Value = 'Leishman 1989';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.loadsTab = uitab(app.TabGroup);
            app.loadsTab.Title = 'loads';
            app.UIAxes = uiaxes(app.loadsTab);
            title(app.UIAxes, 'Normal coefficients')
            xlabel(app.UIAxes, '\alpha (°)')
            ylabel(app.UIAxes, 'C_N  (-)')
            app.UIAxes.XLim = [0 20];
            app.UIAxes.YLim = [-0.5 2.2];
            app.SaveFigureButton = uibutton(app.loadsTab, 'push');
            app.SaveFigureButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButtonPushed, true);
            app.SaveFigureButton.Text = 'Save Figure';
            app.seppointTab = uitab(app.TabGroup);
            app.seppointTab.Title = 'sep. point';
            app.UIAxes2 = uiaxes(app.seppointTab);
            title(app.UIAxes2, 'Separation point')
            xlabel(app.UIAxes2, '\alpha (°)')
            ylabel(app.UIAxes2, 'x/c (-)')
            app.SaveFigureButton_2 = uibutton(app.seppointTab, 'push');
            app.SaveFigureButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButton_2Pushed, true);
            app.SaveFigureButton_2.Text = 'Save Figure';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BLapp2020

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end